classdef FolderOpenermath < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GizleButton      matlab.ui.control.Button
        GzatButton       matlab.ui.control.Button
        TextArea         matlab.ui.control.TextArea
        KontrolEtButton  matlab.ui.control.Button
        Image            matlab.ui.control.Image
    end

    
    properties (Access = private)
        dataFolderTest  % Property to store the selected test data folder
        databaseFileName = 'hiddenDb';
        selectedFolderPath
        directoryToOpen = '';
    end
    
    methods (Access = private)
        
        function Iout = readandpreprocess(app, filename)
            I = imread(filename);
            if ismatrix(I)
                I = cat(3, I, I, I);
            end
            Iout = imresize(I, [224, 224]);
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KontrolEtButton
        function KontrolEtButtonPushed(app, event)
            app.TextArea.Value = " ";
            app.TextArea.Value="Kontrol Ediliyor...";
            pause(0.1);
            app.TextArea.Value = " ";
            if isempty(app.dataFolderTest)
                app.TextArea.Value = [app.TextArea.Value; {'Lütfen veri dosyanızı seçin.'}];
                return;
            end

            loadedData = load(app.databaseFileName);

            dataFolderTrain = 'C:\Users\begum\Desktop\Projects\Yazılım Müh\Dataset\train';
            categories = {'1', '2', '3', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46'};
            
            imdatastTrain = imageDatastore(fullfile(dataFolderTrain, categories), "LabelSource", "foldernames");
            imdatastTrain.ReadFcn = @(filename)readandpreprocess(app,filename);
            
            imdatastTest = imageDatastore(fullfile(app.dataFolderTest, categories), "LabelSource", "foldernames");
            imdatastTest.ReadFcn = @(filename)readandpreprocess(app,filename);
            
            net = googlenet;
            
            featureLayer = 'loss3-classifier';
            
            trainingFeatures = activations(net, imdatastTrain, featureLayer, "OutputAs", "columns");
            testingFeatures = activations(net, imdatastTest, featureLayer, "OutputAs", "columns");
            
            trainingLabels = imdatastTrain.Labels;
            testingLabels = imdatastTest.Labels;
            
            best = 0;
            
            for i = 1:3 % 3 iterasyon çalışacak
                classifier = fitcecoc(trainingFeatures, trainingLabels, "ObservationsIn", "columns", "Learners", "linear");
            
                predictedLabels = predict(classifier, testingFeatures, "ObservationsIn", "columns");
            
                accuracy = mean(predictedLabels == testingLabels);
            
                if accuracy > best
                    best = accuracy;
                    save('model.mat', 'classifier');
                end
                
                if best < 0.85
                    app.TextArea.Value = [app.TextArea.Value; {'Erişim izniniz bulunmamaktadır.'}];
                    break;
                else
                    app.TextArea.Value = [app.TextArea.Value; {sprintf('Iterasyon:%d  Doğruluk:%f', i, accuracy)}];
                end
            end
            
            if best >= 0.85
                    if isfield(loadedData, 'hiddenData')
                        hiddenData = loadedData.hiddenData;

                        app.TextArea.Value = [app.TextArea.Value; {sprintf('Girişe izin veriliyor. En iyi doğruluk: %f\n', best)}];
                    
                        % .mat dosyasına kaydedilen dosya yolu
                        hiddenFolderPath = hiddenData.folderPath;
                    
                        % Dosya yolunu winopen ile aç
                        winopen(hiddenFolderPath);
                    else
                        app.TextArea.Value = [app.TextArea.Value; {'Aradığınız klasör veritabanında bulunmuyor.'}];
                    end
            end
            

        end

        % Button pushed function: GzatButton
        function GzatButtonPushed(app, event)
            app.TextArea.Value = " ";
            % Open a dialog for folder selection
            selectedFolder = uigetdir('C:\Users\begum\', 'Veri klasörünüzü seçin.');
            
            % Check if the user selected a folder
            if selectedFolder ~= 0
                % Update the test data folder
                app.dataFolderTest = selectedFolder;
                app.TextArea.Value = [app.TextArea.Value; {sprintf('Seçilen veri klasörü: %s', app.dataFolderTest)}];
            else
                app.TextArea.Value = [app.TextArea.Value; {'Hiçbir klasör seçilmedi.'}];
            end
        end

        % Button pushed function: GizleButton
        function GizleButtonPushed(app, event)
            app.TextArea.Value = " ";
            app.selectedFolderPath = uigetdir('C:\Users\begum\', 'Gizlenecek klasörü seçin.');

            if ~isequal(app.selectedFolderPath, 0)
                % Set the 'Hidden' attribute for the selected folder
                try
                    fileattrib(app.selectedFolderPath, '+h');
                    app.TextArea.Value = [app.TextArea.Value; {'Klasör başarıyla gizlendi.'}];
        
                    % Save the hidden folder path to a .mat file
                    hiddenData.folderPath = app.selectedFolderPath;
                    hiddenData.timestamp = datetime('now');
                    save(app.databaseFileName, 'hiddenData');
        
                catch
                    app.TextArea.Value = [app.TextArea.Value; {'Klasörü gizlerken hata oluştu.'}];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'biometrics-concept-facial-recognition-system-face-recognition-iris-recognition-scaled.jpg');
            app.KontrolEtButton = uibutton(app.UIFigure, 'push');
            app.KontrolEtButton.ButtonPushedFcn = createCallbackFcn(app, @KontrolEtButtonPushed, true);
            app.KontrolEtButton.Text = 'Kontrol Et';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.GzatButton = uibutton(app.UIFigure, 'push');
            app.GzatButton.ButtonPushedFcn = createCallbackFcn(app, @GzatButtonPushed, true);
            app.GzatButton.Text = 'Gözat';
            app.GizleButton = uibutton(app.UIFigure, 'push');
            app.GizleButton.ButtonPushedFcn = createCallbackFcn(app, @GizleButtonPushed, true);
            app.GizleButton.Text = 'Gizle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FolderOpenermath

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end